classdef button_test_sequence_generation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartcollectionButton   matlab.ui.control.Button
        GestureisolationButton  matlab.ui.control.StateButton
        STOPButton              matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartcollectionButton
        function StartcollectionButtonPushed(app, event)
            assignin('base','store_mark',1)
        end

        % Value changed function: GestureisolationButton
        function GestureisolationButtonValueChanged(app, event)
            value = app.GestureisolationButton.Value;
            assignin('base','toggle',value);
            if value == 1
                app.GestureisolationButton.BackgroundColor = [0,1,0];
            end
            if value == 0
                app.GestureisolationButton.BackgroundColor = [0.94,0.94,0.94];
            end
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            assignin('base','stop',value);
            if value == 1
                app.STOPButton.BackgroundColor = [1,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartcollectionButton = uibutton(app.UIFigure, 'push');
            app.StartcollectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartcollectionButtonPushed, true);
            app.StartcollectionButton.Text = 'Start collection';
            app.GestureisolationButton = uibutton(app.UIFigure, 'state');
            app.GestureisolationButton.ValueChangedFcn = createCallbackFcn(app, @GestureisolationButtonValueChanged, true);
            app.GestureisolationButton.Text = 'Gesture isolation';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = button_test_sequence_generation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end