classdef button_train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GestureselectionButton  matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        GestureStartEndButton   matlab.ui.control.StateButton
        StopButton              matlab.ui.control.StateButton
        EditField               matlab.ui.control.EditField
        EditField_2             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GestureselectionButton
        function GestureselectionButtonPushed(app, event)
            global Gesture_ind cnt
            Gesture_ind = Gesture_ind+1;
            app.EditField.Value = sprintf('선택된 동작은 RG%.0f 입니다.',Gesture_ind);
            cnt = 0;
            app.EditField_2.Value = sprintf('선택된 동작이 총 %.0f개 저장되었습니다.',cnt);
        end

        % Value changed function: GestureStartEndButton
        function GestureStartEndButtonValueChanged(app, event)
            value = app.GestureStartEndButton.Value;
            assignin('base','toggle',value);
            global cnt
            if value == 1
                app.GestureStartEndButton.BackgroundColor = [0,1,0];
            end
            if value == 0
                app.GestureStartEndButton.BackgroundColor = [0.94,0.94,0.94];
                cnt = cnt + 1;
                app.EditField_2.Value = sprintf('선택된 동작이 총 %.0f개 저장되었습니다.',cnt);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            assignin('base','cancel',1);
            global cnt
            cnt = cnt-1;
            app.EditField_2.Value = sprintf('선택된 동작이 총 %.0f개 저장되었습니다.',cnt);
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            assignin('base','stop',value);
            if value == 1
                app.StopButton.BackgroundColor = [1,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GestureselectionButton = uibutton(app.UIFigure, 'push');
            app.GestureselectionButton.ButtonPushedFcn = createCallbackFcn(app, @GestureselectionButtonPushed, true);
            app.GestureselectionButton.Text = 'Gesture selection';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GestureStartEndButton = uibutton(app.UIFigure, 'state');
            app.GestureStartEndButton.ValueChangedFcn = createCallbackFcn(app, @GestureStartEndButtonValueChanged, true);
            app.GestureStartEndButton.Text = 'Gesture Start/End';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = button_train

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end