classdef button_MinMax < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        MINButton   matlab.ui.control.StateButton
        MAXButton   matlab.ui.control.StateButton
        STOPButton  matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MINButton
        function MINButtonValueChanged(app, event)
            value = app.MINButton.Value;
            assignin('base','min_point',value);
            if value == 1
                app.MINButton.BackgroundColor = [0,1,0];
            end
            if value == 0
                app.MINButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: MAXButton
        function MAXButtonValueChanged(app, event)
            value = app.MAXButton.Value;
            assignin('base','max_point',value);
            if value == 1
                app.MAXButton.BackgroundColor = [0,1,0];
            end
            if value == 0
                app.MAXButton.BackgroundColor = [0.96,0.96,0.96];
            end            
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            assignin('base','stop',value);
            if value == 1
                app.STOPButton.BackgroundColor = [1,0,0];
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MINButton = uibutton(app.UIFigure, 'state');
            app.MINButton.ValueChangedFcn = createCallbackFcn(app, @MINButtonValueChanged, true);
            app.MINButton.Text = 'MIN';
            app.MAXButton = uibutton(app.UIFigure, 'state');
            app.MAXButton.ValueChangedFcn = createCallbackFcn(app, @MAXButtonValueChanged, true);
            app.MAXButton.Text = 'MAX';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = button_MinMax

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end